classdef DummyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ListBox       matlab.ui.control.ListBox
        ListBoxLabel  matlab.ui.control.Label
        UITable       matlab.ui.control.Table
        Switch        matlab.ui.control.Switch
        SwitchLabel   matlab.ui.control.Label
        Lamp          matlab.ui.control.Lamp
        LampLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            a=5;
            app.UITable.Data = ["", "5"; "", "GTA"];
            % allMLFBS = getMLFB();
            allMLFBS = {'Select MLFB';'Select All';'MLFB1';'MLFB2';'MLFB3'};
            app.ListBox.Items = allMLFBS;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmpi(value, 'on')
                app.Lamp.Color = 'green';
            else
                app.Lamp.Color = 'red';
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.UITable.Data(indices(1), indices(2)) = newData;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            if strcmp(value, 'Select All')
                selectedMLFBs = app.ListBox.Items(3:end);
            else
                selectedMLFBs = value;
            end
            % topoArray = [];
            % for mlfbCnt = 1:
            % a = func.getDataforMLFB("topology", dataStruct, selectedMLFBs(mlfbCnt))
            % topoArray = [topoArray a];
            % end
            % unique -> if length == 1 else different toplogies
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'MLFB1'; 'Units'};
            app.UITable.RowName = {'Param1'; 'Param2'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Select MLFB', 'Select All', 'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DummyApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end