classdef SeffyCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SeffyCalcToolUIFigure         matlab.ui.Figure
        ORLabel                       matlab.ui.control.Label
        ExportResultsButton           matlab.ui.control.Button
        StartSimulationButton         matlab.ui.control.Button
        SetOperatingConditionsButton  matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        selectedExcelList             matlab.ui.control.ListBox
        SelectedFilesLabel            matlab.ui.control.Label
        ProceedForToplogySelection    matlab.ui.control.Button
        BrowseExcelFilesButton        matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        excelPath string
        excelFile
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: BrowseExcelFilesButton
        function BrowseExcelFilesButtonPushed(app, event)
            [app.excelFile, app.excelPath] = uigetfile(["*.xlsm", ".xlsx"], "Please select required excel file(s)", "MultiSelect","on");
            if ~isempty(app.excelFile) && ~iscell(app.excelFile)
                app.excelFile = {app.excelFile};
            end
            app.selectedExcelList.Items = app.excelFile;
            figure(app.SeffyCalcToolUIFigure);
        end

        % Button pushed function: ProceedForToplogySelection
        function ProceedTopologuSelectionButtonPushed(app, event)
           LossCalcApp = LossCalc(app, app.selectedExcelList.Items);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SeffyCalcToolUIFigure = uifigure('Visible', 'off');
            app.SeffyCalcToolUIFigure.Name = 'Seffy Calc Tool';
            app.UIAxes = uiaxes(app.SeffyCalcToolUIFigure);
            title(app.UIAxes, 'Topology Graphical Representation')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BrowseExcelFilesButton = uibutton(app.SeffyCalcToolUIFigure, 'push');
            app.BrowseExcelFilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseExcelFilesButtonPushed, true);
            app.BrowseExcelFilesButton.Tag = 'browseExcel';
            app.BrowseExcelFilesButton.Text = 'Browse Excel File(s)';
            app.ProceedForToplogySelection = uibutton(app.SeffyCalcToolUIFigure, 'push');
            app.ProceedForToplogySelection.ButtonPushedFcn = createCallbackFcn(app, @ProceedTopologuSelectionButtonPushed, true);
            app.ProceedForToplogySelection.Text = 'Select Topology';
            app.SelectedFilesLabel = uilabel(app.SeffyCalcToolUIFigure);
            app.SelectedFilesLabel.Text = 'Selected Files';
            app.selectedExcelList = uilistbox(app.SeffyCalcToolUIFigure);
            app.selectedExcelList.Items = {};
            app.selectedExcelList.Tag = 'selectedExcelFiles';
            app.selectedExcelList.Enable = 'off';
            app.selectedExcelList.Tooltip = {'Selected Excel Files'};
            app.selectedExcelList.Value = {};
            app.Image = uiimage(app.SeffyCalcToolUIFigure);
            app.SetOperatingConditionsButton = uibutton(app.SeffyCalcToolUIFigure, 'push');
            app.SetOperatingConditionsButton.Text = 'Set Operating Conditions';
            app.StartSimulationButton = uibutton(app.SeffyCalcToolUIFigure, 'push');
            app.StartSimulationButton.Text = 'Start Simulation';
            app.ExportResultsButton = uibutton(app.SeffyCalcToolUIFigure, 'push');
            app.ExportResultsButton.Text = 'Export Results';
            app.ORLabel = uilabel(app.SeffyCalcToolUIFigure);
            app.ORLabel.Text = 'OR';
            % Show the figure after all components are created
            app.SeffyCalcToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeffyCalc

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SeffyCalcToolUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SeffyCalcToolUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SeffyCalcToolUIFigure)
        end
    end
end