classdef upCounterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulationStatusEditField       matlab.ui.control.EditField
        SimulationStatusEditFieldLabel  matlab.ui.control.Label
        SimulationSwitchPauseResume     matlab.ui.control.Switch
        SimulationSwitchStartStop       matlab.ui.control.Switch
        MaxCountEditField               matlab.ui.control.EditField
        MaxCountEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        modelName = 'upCounter';
        maxCntValue = 5;
    end

    methods (Access = private)
        function simInp = createSimulationInput(app)
            % Create an empty SimulationInput object
            simInp = Simulink.SimulationInput(app.modelName);

            % Specify External Inputs function to provide the input force
            % get the current simulation results and update plots while sim
            % is running
            simInp = simulink.compiler.setExternalInputsFcn(simInp, @app.setInputAndUpdateResults);

            % Load the parameters values from the ui edit fields
            simInp = simInp.setVariable('maxCnt', app.maxCntValue); %, 'Workspace', 'MassSpringDamperModel');

            % Since sim will run forever, turn off (or limit) data logging
            simInp = simInp.setModelParameter('StopTime','inf');

            simInp = simulink.compiler.configureForDeployment(simInp);

        end % createSimulationInput

        function modifyParamterDuringSim(app, prmName, prmValue)
            ss = simulink.compiler.getSimulationStatus(app.modelName);
            if (ss ~= slsim.SimulationStatus.Running && ...
                    ss ~= slsim.SimulationStatus.Paused)
                return;
            end
            slv = Simulink.Simulation.Variable(prmName, prmValue);
            simulink.compiler.modifyParameters(app.modelName, slv);
            msgbox(strcat("Parameter '", prmName, "' is been updated to value  ",string(prmValue)))
        end % modifyParamterDuringSim
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MaxCountEditField.Value = string(app.maxCntValue);
        end

        % Callback function
        function StartStopButton_5Pushed(app, event)
            try
                app.StartStopButton_5.Text = 'Starting ...';
                pause(1);
                simInp = app.createSimulationInput();
                simOut = sim(simInp);
                app.StartStopButton_5.Text = 'Simulation Running';
                app.updateResults(simOut, true);
            catch ME
                % Handle error during sim
                if ~isempty(ME.cause), ME = ME.cause{1}; end
                uialert(app.UIFigure, getReport(ME,'extended','hyperlinks','off'), 'Error');
            end
        end

        % Value changed function: MaxCountEditField
        function MaxCountEditFieldValueChanged(app, event)
            app.modifyParamterDuringSim('maxCnt',str2num(app.MaxCountEditField.Value));
        end

        % Value changed function: SimulationSwitchStartStop
        function SimulationSwitchStartStopValueChanged(app, event)
            value = app.SimulationSwitchStartStop.Value;
            try
                if strcmp(value, 'Start')
                    app.SimulationStatusEditField.Value = 'Running';
                    app.SimulationStatusEditField.FontColor = [0 1 0];
                    app.SimulationSwitchPauseResume.Enable = "on";
                    simInp = app.createSimulationInput();
                    simOut = sim(simInp);
                elseif strcmp(value, 'Stop')
                    simulink.compiler.stopSimulation(app.modelName);
                    app.SimulationStatusEditField.Value = 'Stopped';
                    app.SimulationStatusEditField.FontColor = [1 0 0];
                    app.SimulationSwitchPauseResume.Enable = "off";
                end
            catch ME
                % Handle error during sim
                if ~isempty(ME.cause), ME = ME.cause{1}; end
                uialert(app.UIFigure, getReport(ME,'extended','hyperlinks','off'), 'Error');
            end
        end

        % Value changed function: SimulationSwitchPauseResume
        function SimulationSwitchPauseResumeValueChanged(app, event)
            value = app.SimulationSwitchPauseResume.Value;
            try
                if strcmp(app.SimulationSwitchStartStop.Value, 'Start')
                    app.SimulationSwitchPauseResume.Enable = "on";
                    if strcmp(value, 'Pause')
                        simulink.compiler.pauseSimulation(app.modelName);
                        app.SimulationStatusEditField.Value = 'Paused';
                        app.SimulationStatusEditField.FontColor = [1 1 0];
                    elseif strcmp(value, 'Resume')
                        simulink.compiler.resumeSimulation(app.modelName);
                        app.SimulationStatusEditField.Value = 'Running';
                        app.SimulationStatusEditField.FontColor = [0 1 0];
                    end
                else
                    app.SimulationSwitchPauseResume.Value = "Resume";
                    app.SimulationSwitchPauseResume.Enable = "off";
                end
            catch ME
                % Handle error during sim
                if ~isempty(ME.cause), ME = ME.cause{1}; end
                uialert(app.UIFigure, getReport(ME,'extended','hyperlinks','off'), 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MaxCountEditFieldLabel = uilabel(app.UIFigure);
            app.MaxCountEditFieldLabel.Text = 'Max Count';
            app.MaxCountEditField = uieditfield(app.UIFigure, 'text');
            app.MaxCountEditField.ValueChangedFcn = createCallbackFcn(app, @MaxCountEditFieldValueChanged, true);
            app.SimulationSwitchStartStop = uiswitch(app.UIFigure, 'slider');
            app.SimulationSwitchStartStop.Items = {'Start', 'Stop'};
            app.SimulationSwitchStartStop.ValueChangedFcn = createCallbackFcn(app, @SimulationSwitchStartStopValueChanged, true);
            app.SimulationSwitchStartStop.Value = 'Stop';
            app.SimulationSwitchPauseResume = uiswitch(app.UIFigure, 'slider');
            app.SimulationSwitchPauseResume.Items = {'Pause', 'Resume'};
            app.SimulationSwitchPauseResume.ValueChangedFcn = createCallbackFcn(app, @SimulationSwitchPauseResumeValueChanged, true);
            app.SimulationSwitchPauseResume.Enable = 'off';
            app.SimulationSwitchPauseResume.Value = 'Resume';
            app.SimulationStatusEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationStatusEditFieldLabel.Text = 'Simulation Status';
            app.SimulationStatusEditField = uieditfield(app.UIFigure, 'text');
            app.SimulationStatusEditField.Editable = 'off';
            app.SimulationStatusEditField.Enable = 'off';
            app.SimulationStatusEditField.Value = 'Inactive';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = upCounterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end