classdef pdf2txt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        pdf2txtUIFigure           matlab.ui.Figure
        ExplorerFinderopenforWinMacSwitch  matlab.ui.control.Switch
        ExplorerFinderopenforWinMacSwitchLabel  matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        DescriptionThisappistoconverttopdffiletotexttxtfileThefileLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        FilesselectconvertButton  matlab.ui.control.Button
    end


    methods (Access = private)

        function readConvertFile(app,file)

            filename=fullfile(file.path,file.name);

            try
                nFile=length(file.name);
                
                for iFile=1:nFile
                    % FileName process
                    [filepath,name,ext] = fileparts(filename{iFile});
                    
                    % FileCopy (to prevent crashing orizinal pdf file)
                    copiedFilename = fullfile(filepath,strcat('temp',ext));                   
                    copyfile(filename{iFile},copiedFilename,'f');
                    
                    % pdf File read with extractFileText function
                    readText=extractFileText(copiedFilename);
                    
                    % Delete copied File
                    delete(copiedFilename);
                   
                    %% Text Edit (user function)
                    readText=textEdit(app,readText);      
                    
                    % txt output
                    outputFilepath=fullfile(filepath,[name '.txt']);
                    fileID = fopen(outputFilepath,'w');
                    for iLine = 1:length(readText)
                        fprintf(fileID,'%s \n',readText(iLine));
                    end
                    fclose(fileID);              
                    
                end
                app.Label.Text={'Finished!'};
                
                % Explorer/Finder open
                
                if isequal(app.ExplorerFinderopenforWinMacSwitch.Value,'On') && (ispc || ismac)
                    if ispc % Windows PC
                        OpenCmd=horzcat('explorer ',char(filepath) );
                    elseif ismac % Mac PC
                        OpenCmd=horzcat('open ',char(filepath) );
                    end
                    system(OpenCmd);
                end
                
            catch ME
                if exist('copiedFilename')>0
                    delete(copiedFilename);
                end
                mes=strrep(ME.message,copiedFilename,filename{iFile});
                
                app.Label.Text ={strcat('Error:',mes)};
            end

        end
               
        function readText = textEdit(app,readText)
            
            % Remove otiose blank line ("")
            % Lines of after the second in blank line are removed
            
            readText = splitlines(readText);
            TF = (readText == "");
            
            rmTF(1:length(TF)) =false;
            rmTF(3:length(TF)) = TF(1:(end-2)) & TF(2:(end-1)) & TF(3:(end));
            
            readText(rmTF) = [];     
            
            % Sentence coupling (until next blank line) 
            isBlank = (readText == "");
            
            coupledText(1,1)="";            
            for iLine =1:length(readText)
                if isBlank(iLine)==true
                    coupledText(end+1,1)=""; %Blank line
                else
                    coupledText(end,1)=coupledText(end,1)+readText(iLine);
                end                
            end
            readText=coupledText;
            % Other Edit (e.g. Word replace (use "strrep" funciton))


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FilesselectconvertButton
        function FilesselectconvertButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.pdf;*.docx'};
            [file.name,file.path] = uigetfile(filterspec,'MultiSelect','on');
            if isequal(file.name,0)
               
               app.Label.Text={'User selected Cancel'};
            else
                % Convert cell
                if iscell(file.name)==0 
                    file.name=cellstr(file.name);                
                end
                
                % Convert
                readConvertFile(app,file);
            end          
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.pdf2txtUIFigure = uifigure('Visible', 'off');
            app.pdf2txtUIFigure.Name = 'pdf2txt';
            app.FilesselectconvertButton = uibutton(app.pdf2txtUIFigure, 'push');
            app.FilesselectconvertButton.ButtonPushedFcn = createCallbackFcn(app, @FilesselectconvertButtonPushed, true);
            app.FilesselectconvertButton.Text = 'Files select & convert';
            app.Label = uilabel(app.pdf2txtUIFigure);
            app.Label.Text = '';
            app.DescriptionThisappistoconverttopdffiletotexttxtfileThefileLabel = uilabel(app.pdf2txtUIFigure);
            app.DescriptionThisappistoconverttopdffiletotexttxtfileThefileLabel.Text = 'Description: This app converts from pdf file to text (txt) file.';
            app.Label_2 = uilabel(app.pdf2txtUIFigure);
            app.Label_2.Text = 'Multiple pdf files can be selected. ';
            app.Label_3 = uilabel(app.pdf2txtUIFigure);
            app.Label_3.Text = {'Notice: Protected pdf files cannot convert.'; '(Error: "Cannot open file. The file may be broken.")'};
            app.ExplorerFinderopenforWinMacSwitchLabel = uilabel(app.pdf2txtUIFigure);
            app.ExplorerFinderopenforWinMacSwitchLabel.Text = 'Explorer/Finder open (for Win/Mac)';
            app.ExplorerFinderopenforWinMacSwitch = uiswitch(app.pdf2txtUIFigure, 'slider');
            app.ExplorerFinderopenforWinMacSwitch.Value = 'On';
            % Show the figure after all components are created
            app.pdf2txtUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pdf2txt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.pdf2txtUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.pdf2txtUIFigure)
        end
    end
end